classdef ChooseMatrixApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ChoosecurvesLabel   matlab.ui.control.Label
        ForthForthCheckBox  matlab.ui.control.CheckBox
        TopographydirectionIVcurvedirectionLabel  matlab.ui.control.Label
        ForthBackCheckBox   matlab.ui.control.CheckBox
        BackForthCheckBox   matlab.ui.control.CheckBox
        BackBackCheckBox    matlab.ui.control.CheckBox
        GoButton            matlab.ui.control.Button
    end

    
    properties (Access = public)
        eleccionMatrices
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.eleccionMatrices = [app.ForthForthCheckBox.Value, app.ForthBackCheckBox.Value, ...
                app.BackForthCheckBox.Value, app.BackBackCheckBox.Value];
            assignin('base', 'eleccionMatrices', app.eleccionMatrices)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ChoosecurvesLabel = uilabel(app.UIFigure);
            app.ChoosecurvesLabel.Text = 'Choose curves';
            app.ForthForthCheckBox = uicheckbox(app.UIFigure);
            app.ForthForthCheckBox.Text = 'Forth + Forth';
            app.ForthForthCheckBox.Value = true;
            app.TopographydirectionIVcurvedirectionLabel = uilabel(app.UIFigure);
            app.TopographydirectionIVcurvedirectionLabel.Text = 'Topography direction + IV curve direction';
            app.ForthBackCheckBox = uicheckbox(app.UIFigure);
            app.ForthBackCheckBox.Text = 'Forth + Back';
            app.BackForthCheckBox = uicheckbox(app.UIFigure);
            app.BackForthCheckBox.Text = 'Back + Forth';
            app.BackBackCheckBox = uicheckbox(app.UIFigure);
            app.BackBackCheckBox.Text = 'Back + Back';
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChooseMatrixApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end