classdef mapsPreview < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        RealAxes                  matlab.ui.control.UIAxes
        mapsPreviewLabel          matlab.ui.control.Label
        FFTAxes                   matlab.ui.control.UIAxes
        RealMinSlider             matlab.ui.control.Slider
        EnergySpinnerLabel        matlab.ui.control.Label
        EnergySpinner             matlab.ui.control.Spinner
        RealMaxSlider             matlab.ui.control.Slider
        FFTMinSlider              matlab.ui.control.Slider
        FFTMaxSlider              matlab.ui.control.Slider
        RealProfileButton         matlab.ui.control.Button
        FFTProfileButton          matlab.ui.control.Button
        ColormapDropDownLabel     matlab.ui.control.Label
        RealColormapDropDown      matlab.ui.control.DropDown
        ColormapDropDown_2Label   matlab.ui.control.Label
        FFTColormapDropDown       matlab.ui.control.DropDown
        meVLabel                  matlab.ui.control.Label
        RealLockContrastCheckBox  matlab.ui.control.CheckBox
        FFTLockContrastCheckBox   matlab.ui.control.CheckBox
        GenerateInfoStructButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        CallingApp % main app object
        k % map index
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, Struct)
            app.CallingApp = mainApp;
            
            app.CallingApp.Struct.ContrastReal = [];
            app.CallingApp.Struct.ContrastFFT = [];
            
            app.RealAxes.Tag = 'RealAxes';
            app.FFTAxes.Tag = 'FFTAxes';
            
            app.k = app.CallingApp.Struct.kInicial;
            app.EnergySpinner.Limits = [app.CallingApp.Struct.Energia(1), app.CallingApp.Struct.Energia(end)];
            app.EnergySpinner.Step = abs(app.CallingApp.Struct.Energia(2) - app.CallingApp.Struct.Energia(1));
            app.EnergySpinner.Value = app.CallingApp.Struct.Energia(app.k);
            
            app.CallingApp.Struct = showMaps(app, app.CallingApp.Struct, app.k);
        end

        % Value changed function: EnergySpinner
        function EnergySpinnerValueChanged(app, event)
            [~, app.k] = min(abs(app.CallingApp.Struct.Energia - app.EnergySpinner.Value));
            app.EnergySpinner.Value = app.CallingApp.Struct.Energia(app.k);
            app.CallingApp.Struct = showMaps(app, app.CallingApp.Struct, app.k);
        end

        % Value changed function: RealMinSlider
        function RealMinSliderValueChanged(app, event)
            if app.RealMinSlider.Value > app.RealMaxSlider.Value
                app.RealMinSlider.Value = app.RealMaxSlider.Value;
            end
            app.RealAxes.CLim(1) = app.RealMinSlider.Value;
        end

        % Value changed function: RealMaxSlider
        function RealMaxSliderValueChanged(app, event)
            if app.RealMaxSlider.Value < app.RealMinSlider.Value
                app.RealMaxSlider.Value = app.RealMinSlider.Value;
            end
            app.RealAxes.CLim(2) = app.RealMaxSlider.Value;
        end

        % Value changed function: FFTMinSlider
        function FFTMinSliderValueChanged(app, event)
            if app.FFTMinSlider.Value > app.FFTMaxSlider.Value
                app.FFTMinSlider.Value = app.FFTMaxSlider.Value;
            end
            app.FFTAxes.CLim(1) = app.FFTMinSlider.Value;
        end

        % Value changed function: FFTMaxSlider
        function FFTMaxSliderValueChanged(app, event)
            if app.FFTMaxSlider.Value < app.FFTMinSlider.Value
                app.FFTMaxSlider.Value = app.FFTMinSlider.Value;
            end
            app.FFTAxes.CLim(2) = app.FFTMaxSlider.Value;
        end

        % Value changed function: RealColormapDropDown
        function RealColormapDropDownValueChanged(app, event)
            load('JapanColormap.mat')
            value = app.RealColormapDropDown.Value;
            app.RealAxes.Colormap = eval(value);
        end

        % Value changed function: FFTColormapDropDown
        function FFTColormapDropDownValueChanged(app, event)
            load('JapanColormap.mat')
            value = app.FFTColormapDropDown.Value;
            app.FFTAxes.Colormap = eval(value);
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            Down_v2(app.UIFigure)
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            CurrentPoint_v2(app.UIFigure)
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
%             [Ax, In] = chooseAxes_v2(app.UIFigure)
%             Ax.Tag
            
            mapsClick(app, app.CallingApp.Struct)
        end

        % Button pushed function: GenerateInfoStructButton
        function GenerateInfoStructButtonPushed(app, event)
            generateInfo(app, app.CallingApp.Struct)
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
%             verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            if verticalScrollCount < 0
                if app.EnergySpinner.Value ~= app.EnergySpinner.Limits(2)
                    app.EnergySpinner.Value = app.EnergySpinner.Value + (-1*verticalScrollCount)*app.EnergySpinner.Step;
                    app.EnergySpinnerValueChanged
                end
            else
                if app.EnergySpinner.Value ~= app.EnergySpinner.Limits(1)
                    app.EnergySpinner.Value = app.EnergySpinner.Value + (-1*verticalScrollCount)*app.EnergySpinner.Step;
                    app.EnergySpinnerValueChanged
                end
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.RealAxes = uiaxes(app.UIFigure);
            title(app.RealAxes, 'Conductance map')
            xlabel(app.RealAxes, 'Distance (nm)')
            ylabel(app.RealAxes, 'Distance (nm)')
            app.mapsPreviewLabel = uilabel(app.UIFigure);
            app.mapsPreviewLabel.Text = 'mapsPreview';
            app.FFTAxes = uiaxes(app.UIFigure);
            title(app.FFTAxes, 'FFT')
            xlabel(app.FFTAxes, 'k (nm^{-1})')
            ylabel(app.FFTAxes, 'k (nm^{-1})')
            app.RealMinSlider = uislider(app.UIFigure);
            app.RealMinSlider.MajorTicks = [];
            app.RealMinSlider.MajorTickLabels = {''};
            app.RealMinSlider.ValueChangedFcn = createCallbackFcn(app, @RealMinSliderValueChanged, true);
            app.RealMinSlider.MinorTicks = [];
            app.EnergySpinnerLabel = uilabel(app.UIFigure);
            app.EnergySpinnerLabel.Text = 'Energy';
            app.EnergySpinner = uispinner(app.UIFigure);
            app.EnergySpinner.ValueChangedFcn = createCallbackFcn(app, @EnergySpinnerValueChanged, true);
            app.RealMaxSlider = uislider(app.UIFigure);
            app.RealMaxSlider.MajorTicks = [];
            app.RealMaxSlider.MajorTickLabels = {''};
            app.RealMaxSlider.ValueChangedFcn = createCallbackFcn(app, @RealMaxSliderValueChanged, true);
            app.RealMaxSlider.MinorTicks = [];
            app.RealMaxSlider.Value = 100;
            app.FFTMinSlider = uislider(app.UIFigure);
            app.FFTMinSlider.MajorTicks = [];
            app.FFTMinSlider.MajorTickLabels = {''};
            app.FFTMinSlider.ValueChangedFcn = createCallbackFcn(app, @FFTMinSliderValueChanged, true);
            app.FFTMinSlider.MinorTicks = [];
            app.FFTMaxSlider = uislider(app.UIFigure);
            app.FFTMaxSlider.MajorTicks = [];
            app.FFTMaxSlider.MajorTickLabels = {''};
            app.FFTMaxSlider.ValueChangedFcn = createCallbackFcn(app, @FFTMaxSliderValueChanged, true);
            app.FFTMaxSlider.MinorTicks = [];
            app.FFTMaxSlider.Value = 100;
            app.RealProfileButton = uibutton(app.UIFigure, 'push');
            app.RealProfileButton.Text = 'Profile';
            app.FFTProfileButton = uibutton(app.UIFigure, 'push');
            app.FFTProfileButton.Text = 'Profile';
            app.ColormapDropDownLabel = uilabel(app.UIFigure);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.RealColormapDropDown = uidropdown(app.UIFigure);
            app.RealColormapDropDown.Items = {'gray', 'parula', 'jet', 'winter', 'hot', 'spring', 'summer', 'autumn', 'bone', 'copper', 'JapanColormap'};
            app.RealColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @RealColormapDropDownValueChanged, true);
            app.RealColormapDropDown.Value = 'gray';
            app.ColormapDropDown_2Label = uilabel(app.UIFigure);
            app.ColormapDropDown_2Label.Text = 'Colormap';
            app.FFTColormapDropDown = uidropdown(app.UIFigure);
            app.FFTColormapDropDown.Items = {'gray', 'parula', 'jet', 'winter', 'hot', 'spring', 'summer', 'autumn', 'bone', 'copper', 'JapanColormap'};
            app.FFTColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @FFTColormapDropDownValueChanged, true);
            app.FFTColormapDropDown.Value = 'gray';
            app.meVLabel = uilabel(app.UIFigure);
            app.meVLabel.Text = 'meV';
            app.RealLockContrastCheckBox = uicheckbox(app.UIFigure);
            app.RealLockContrastCheckBox.Text = 'Lock contrast';
            app.FFTLockContrastCheckBox = uicheckbox(app.UIFigure);
            app.FFTLockContrastCheckBox.Text = 'Lock contrast';
            app.GenerateInfoStructButton = uibutton(app.UIFigure, 'push');
            app.GenerateInfoStructButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateInfoStructButtonPushed, true);
            app.GenerateInfoStructButton.Text = 'Generate InfoStruct';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mapsPreview(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end