classdef blqApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        blqAnalysisUIFigure           matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        InfoStructtoworkspaceMenu     matlab.ui.container.Menu
        EditMenu                      matlab.ui.container.Menu
        RecalculateFFTMenu            matlab.ui.container.Menu
        RemoveCentralLineMenu         matlab.ui.container.Menu
        VerticalMenu                  matlab.ui.container.Menu
        HorizontalMenu                matlab.ui.container.Menu
        VerticalHorizontalMenu        matlab.ui.container.Menu
        SymmetrizeMenu                matlab.ui.container.Menu
        VerticalmirrorMenu            matlab.ui.container.Menu
        VerticalHorizontalmirrorMenu  matlab.ui.container.Menu
        C4symmetryMenu                matlab.ui.container.Menu
        CellMenu                      matlab.ui.container.Menu
        NewMenu                       matlab.ui.container.Menu
        OldMenu                       matlab.ui.container.Menu
        TabGroup                      matlab.ui.container.TabGroup
        OpenTab                       matlab.ui.container.Tab
        OldNewButton                  matlab.ui.control.Button
        SaveInfostructButton          matlab.ui.control.Button
        LoadInfostructfromfileButton  matlab.ui.control.Button
        ClearallButton                matlab.ui.control.Button
        NewAppButton                  matlab.ui.control.Button
        LoadInfoStructfromworkspaceButton  matlab.ui.control.Button
        CutInfoStructButton           matlab.ui.control.Button
        x1EditFieldLabel              matlab.ui.control.Label
        x1EditField                   matlab.ui.control.NumericEditField
        x2EditFieldLabel              matlab.ui.control.Label
        x2EditField                   matlab.ui.control.NumericEditField
        y1EditFieldLabel              matlab.ui.control.Label
        y1EditField                   matlab.ui.control.NumericEditField
        y2EditFieldLabel              matlab.ui.control.Label
        y2EditField                   matlab.ui.control.NumericEditField
        RestoreButton                 matlab.ui.control.Button
        AnalizeRealTab                matlab.ui.container.Tab
        RealMapsButton                matlab.ui.control.Button
        ColormapRealDropDown          matlab.ui.control.DropDown
        KeepChangesButton             matlab.ui.control.Button
        VisualizeRealButton           matlab.ui.control.Button
        MeanConductanceButton         matlab.ui.control.Button
        RotateButton_2                matlab.ui.control.Button
        EditRotateAngleReal           matlab.ui.control.NumericEditField
        SaveallRealButton             matlab.ui.control.Button
        GaussianFilterButton_2        matlab.ui.control.Button
        EditGaussianFilterReal        matlab.ui.control.NumericEditField
        MeanIVButton                  matlab.ui.control.Button
        SaveContrastRealButton        matlab.ui.control.Button
        LoadContrastRealButton        matlab.ui.control.Button
        testButton                    matlab.ui.control.Button
        AnalizeFFTTab                 matlab.ui.container.Tab
        FFTMapsButton                 matlab.ui.control.Button
        ColormapFFTDropDown           matlab.ui.control.DropDown
        RemoveCentralLineButton       matlab.ui.control.Button
        RotateButton                  matlab.ui.control.Button
        EditRotateAngleFFT            matlab.ui.control.NumericEditField
        SymmetrizeButton              matlab.ui.control.Button
        AdjustZeroButton              matlab.ui.control.Button
        SaveallFFTButton              matlab.ui.control.Button
        GaussianFilterButton          matlab.ui.control.Button
        EditGaussianFilterFFT         matlab.ui.control.NumericEditField
        AngleprofileButton            matlab.ui.control.Button
        EditProfileAngle              matlab.ui.control.NumericEditField
        RadialprofileButton           matlab.ui.control.Button
        VisualizeFFTButton            matlab.ui.control.Button
        KeepChangesButton_2           matlab.ui.control.Button
        VerticalprofileButton         matlab.ui.control.Button
        HorizontalprofileButton       matlab.ui.control.Button
        DiagonalprofileButton         matlab.ui.control.Button
        SaveContrastFFTButton         matlab.ui.control.Button
        LoadContrastFFTButton         matlab.ui.control.Button
        CutasviewButton               matlab.ui.control.Button
        MoreFunctionsTab              matlab.ui.container.Tab
        AveragescatteringButton       matlab.ui.control.Button
        blqAnalysisQPIStudyLabel      matlab.ui.control.Label
    end


    properties (Access = public)
        Property % Description
        % Variables de la aplicación
        InfoStruct
        InfoStructOriginal
        InfoStructAnalisis
        InfoStructAnalisisCopy
        InfoData 
        
        fourierImage
        realImage
        
        JapanColormap
                
        Type
        New
        
        V
        H
        
        % Variables coming from blqReaderApp
        blqReaderApp % App used to open blq file 
        Struct
        MatrizCorriente
        Voltaje
        %Variables coming from mapsPreviewApp
        mapsPreviewApp % App used to show generated maps
        visualizeAppReal  % App used to visualize Real space analysis
        visualizeAppFFT % App used to visualize FFT analysis
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            genpath('Functions');
            addpath(genpath('Functions'));
            
            genpath('Colormap');
            addpath(genpath('Colormap'));
            
%             load('JapanColormap.mat')
%             app.JapanColormap = JapanColormap;
%             genpath('Icons');
%             addpath(genpath('Icons'));         

            app.Type = 3;
            app.New = true;
            
            app.V = true;
            app.H = false;
        end

        % Button pushed function: OldNewButton
        function OldNewButtonPushed(app, event)
            ImageAnalysisIV
        end

        % Button pushed function: NewAppButton
        function NewAppButtonPushed(app, event)
            app.blqReaderApp = blqReader(app);
        end

        % Button pushed function: SaveInfostructButton
        function SaveInfostructButtonPushed(app, event)
            saveInfo(app, app.Struct)
        end

        % Button pushed function: LoadInfostructfromfileButton
        function LoadInfostructfromfileButtonPushed(app, event)
            app.InfoStruct = openInfo();
            app.InfoStructOriginal = app.InfoStruct;
            figure(app.blqAnalysisUIFigure)
            msgbox('InfoStruct succesfully loaded.','You are amazing','help')
%             app.InfoStruct.Colormap = eval(app.ColormapFFTDropDown.Value);
        end

        % Button pushed function: FFTMapsButton
        function FFTMapsButtonPushed(app, event)
            load('JapanColormap.mat')
            app.InfoStruct.Colormap = eval(app.ColormapFFTDropDown.Value);
            app.InfoStructAnalisis.Colormap = eval(app.ColormapFFTDropDown.Value);
            app.InfoStructAnalisisCopy.Colormap = eval(app.ColormapFFTDropDown.Value);
            VisualizeCell(app.InfoStruct.Transformadas,app.InfoStruct)
            app.fourierImage = gcf;
            %             app.fourierImage.UserData.Colormap = app.InfoStruct.Colormap;
            app.fourierImage.Colormap = eval(app.ColormapFFTDropDown.Value);
            
            app.InfoStruct = app.fourierImage.UserData;
            app.InfoStructAnalisis = app.fourierImage.UserData;
            app.InfoStructAnalisisCopy = app.InfoStructAnalisis;
            
            %             app.TransformadasAnalisis = app.InfoStruct.Transformadas;
        end

        % Button pushed function: KeepChangesButton
        function KeepChangesButtonPushed(app, event)
            app.InfoStructAnalisis = app.realImage.UserData;
            app.InfoStructAnalisis.MapasConductancia = app.InfoStructAnalisisCopy.MapasConductancia;
            app.InfoStructAnalisisCopy = app.InfoStructAnalisis;
        end

        % Button pushed function: RemoveCentralLineButton
        function RemoveCentralLineButtonPushed(app, event)
            app.InfoStructAnalisisCopy.Transformadas = RemoveCentralLine(app.InfoStructAnalisis.Transformadas, app.InfoStructAnalisis, app.V, app.H);
            VisualizeCell(app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy);
        end

        % Value changed function: ColormapFFTDropDown
        function ColormapFFTDropDownValueChanged(app, event)
            load('JapanColormap.mat')
            value = app.ColormapFFTDropDown.Value;
            app.fourierImage.Colormap = eval(value);
            
            app.fourierImage.UserData.Colormap = eval(value);
            app.InfoStruct.Colormap = eval(value);
            app.InfoStructAnalisis.Colormap = eval(value);
            app.InfoStructAnalisisCopy.Colormap = eval(value);
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            app.InfoStructAnalisisCopy.Transformadas = RotateMatrix(app.InfoStructAnalisis.Transformadas, app.EditRotateAngleFFT.Value, app.InfoStructAnalisis);
            VisualizeCell(app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy);
        end

        % Button pushed function: SymmetrizeButton
        function SymmetrizeButtonPushed(app, event)
            app.InfoStructAnalisisCopy.Transformadas = simetrizarFFT_Automatico(app.InfoStructAnalisis.Transformadas, 0, app.Type,app.New);
            VisualizeCell(app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy);
        end

        % Button pushed function: GaussianFilterButton
        function GaussianFilterButtonPushed(app, event)
            if app.EditGaussianFilterFFT.Value > 0
                app.InfoStructAnalisisCopy.Transformadas = GaussSmooth(app.InfoStructAnalisis.Transformadas, app.EditGaussianFilterFFT.Value, app.InfoStructAnalisis);                
            end
            VisualizeCell(app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy);
        end

        % Button pushed function: AdjustZeroButton
        function AdjustZeroButtonPushed(app, event)
            [app.InfoStructAnalisisCopy.Transformadas,  app.InfoStructAnalisisCopy] = adjustZero(app.InfoStructAnalisis.Transformadas, app.InfoStructAnalisis);
            VisualizeCell(app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy);
        end

        % Button pushed function: SaveallFFTButton
        function SaveallFFTButtonPushed(app, event)
            saveCell(app.InfoStructAnalisis.Transformadas, app.InfoStructAnalisis)
            msgbox([num2str(length(app.InfoStruct.Energia)) ' png files succesfully saved.'],'Follow your dreams','help')
        end

        % Button pushed function: MeanConductanceButton
        function MeanConductanceButtonPushed(app, event)
            meanConductance(app.InfoStruct)
        end

        % Button pushed function: VerticalprofileButton
        function VerticalprofileButtonPushed(app, event)
            verticalProfile(app.InfoStructAnalisis)
        end

        % Button pushed function: HorizontalprofileButton
        function HorizontalprofileButtonPushed(app, event)
            horizontalProfile(app.InfoStructAnalisis)
        end

        % Button pushed function: DiagonalprofileButton
        function DiagonalprofileButtonPushed(app, event)
            diagonalProfile(app.InfoStructAnalisis)
        end

        % Button pushed function: ClearallButton
        function ClearallButtonPushed(app, event)
            clear variables
            evalin('base','clear variables')
        end

        % Button pushed function: KeepChangesButton_2
        function KeepChangesButton_2Pushed(app, event)
            for i=1:length(app.InfoStruct.Energia)
                app.fourierImage.UserData.XLim(1:2,i) = app.fourierImage.Children.XLim;
                app.fourierImage.UserData.YLim(1:2,i) = app.fourierImage.Children.YLim;
            end            
            app.InfoStructAnalisis = app.fourierImage.UserData;
            app.InfoStructAnalisis.Transformadas = app.InfoStructAnalisisCopy.Transformadas;
            app.InfoStructAnalisisCopy = app.InfoStructAnalisis;
            %             app.InfoStructAnalisisCopy.Contrast = app.fourierImage.UserData.Contrast;
            
            %             app.TransformadasAnalisis = app.TransformadasAnalisisCopy;
        end

        % Button pushed function: VisualizeFFTButton
        function VisualizeFFTButtonPushed(app, event)
            VisualizeCell(app.InfoStructAnalisis.Transformadas,app.InfoStructAnalisisCopy)
        end

        % Menu selected function: InfoStructtoworkspaceMenu
        function InfoStructtoworkspaceMenuSelected(app, event)
            assignin('base','InfoStruct',app.InfoStruct)
            assignin('base','InfoStructAnalisis',app.InfoStructAnalisis)
            assignin('base','InfoStructAnalisisCopy',app.InfoStructAnalisisCopy)
            assignin('base','Struct',app.Struct)
%             assignin('base','fourierImage',app.fourierImage)
        end

        % Menu selected function: VerticalmirrorMenu
        function VerticalmirrorMenuSelected(app, event)
            app.VerticalmirrorMenu.Checked = 1;
            app.VerticalHorizontalmirrorMenu.Checked = 0;
            app.C4symmetryMenu.Checked = 0;
            
            app.Type = 2;
        end

        % Menu selected function: VerticalHorizontalmirrorMenu
        function VerticalHorizontalmirrorMenuSelected(app, event)
            app.VerticalmirrorMenu.Checked = 0;
            app.VerticalHorizontalmirrorMenu.Checked = 1;
            app.C4symmetryMenu.Checked = 0;
            
            app.Type = 1;
        end

        % Menu selected function: C4symmetryMenu
        function C4symmetryMenuSelected(app, event)
            app.VerticalmirrorMenu.Checked = 0;
            app.VerticalHorizontalmirrorMenu.Checked = 0;
            app.C4symmetryMenu.Checked = 1;
            
            app.Type = 3;
        end

        % Button pushed function: RealMapsButton
        function RealMapsButtonPushed(app, event)
            app.InfoStruct.Colormap = eval(app.ColormapRealDropDown.Value);
            app.InfoStructAnalisis.Colormap = eval(app.ColormapRealDropDown.Value);
            app.InfoStructAnalisisCopy.Colormap = eval(app.ColormapRealDropDown.Value);
            VisualizeCellReal(app.InfoStruct.MapasConductancia,app.InfoStruct)
            app.realImage = gcf;
            %             app.fourierImage.UserData.Colormap = app.InfoStruct.Colormap;
            app.realImage.Colormap = eval(app.ColormapRealDropDown.Value);
            
            app.InfoStruct = app.realImage.UserData;
            app.InfoStructAnalisis = app.realImage.UserData;
            app.InfoStructAnalisisCopy = app.InfoStructAnalisis;
        end

        % Value changed function: ColormapRealDropDown
        function ColormapRealDropDownValueChanged(app, event)
            load('JapanColormap.mat')
            value = app.ColormapRealDropDown.Value;
            app.realImage.Colormap = eval(value);
            
            app.realImage.UserData.Colormap = eval(value);
            app.InfoStruct.Colormap = eval(value);
            app.InfoStructAnalisis.Colormap = eval(value);
            app.InfoStructAnalisisCopy.Colormap = eval(value);
        end

        % Button pushed function: VisualizeRealButton
        function VisualizeRealButtonPushed(app, event)
            VisualizeCellReal(app.InfoStructAnalisis.MapasConductancia,app.InfoStructAnalisisCopy)
        end

        % Button pushed function: RotateButton_2
        function RotateButton_2Pushed(app, event)
            app.InfoStructAnalisisCopy.MapasConductancia = RotateMatrix(app.InfoStructAnalisis.MapasConductancia, app.EditRotateAngleReal.Value, app.InfoStructAnalisis);
            VisualizeCellReal(app.InfoStructAnalisisCopy.MapasConductancia, app.InfoStructAnalisisCopy);
        end

        % Button pushed function: GaussianFilterButton_2
        function GaussianFilterButton_2Pushed(app, event)
            if app.EditGaussianFilterReal.Value > 0
                app.InfoStructAnalisisCopy.MapasConductancia = GaussSmooth(app.InfoStructAnalisis.MapasConductancia, app.EditGaussianFilterReal.Value, app.InfoStructAnalisis);                
            end
            VisualizeCellReal(app.InfoStructAnalisisCopy.MapasConductancia, app.InfoStructAnalisisCopy);
        end

        % Button pushed function: MeanIVButton
        function MeanIVButtonPushed(app, event)
            meanIV(app.InfoStruct)
        end

        % Button pushed function: SaveallRealButton
        function SaveallRealButtonPushed(app, event)
            saveCell(app.InfoStructAnalisis.MapasConductancia, app.InfoStructAnalisis)
            msgbox([num2str(length(app.InfoStruct.Energia)) ' png files succesfully saved.'],'Follow your dreams','help')
        end

        % Button pushed function: SaveContrastFFTButton
        function SaveContrastFFTButtonPushed(app, event)
            assignin('base','ContrastFFT',app.InfoStructAnalisis.ContrastFFT)
        end

        % Button pushed function: LoadContrastFFTButton
        function LoadContrastFFTButtonPushed(app, event)
            Contrast = evalin('base','ContrastFFT');
            app.fourierImage.UserData.ContrastFFT = Contrast;
            app.InfoStruct.ContrastFFT = Contrast;
            app.InfoStructAnalisis.ContrastFFT = Contrast;
            app.InfoStructAnalisisCopy.ContrastFFT = Contrast;
        end

        % Button pushed function: SaveContrastRealButton
        function SaveContrastRealButtonPushed(app, event)
            assignin('base','ContrastReal',app.InfoStructAnalisis.ContrastReal)
        end

        % Button pushed function: LoadContrastRealButton
        function LoadContrastRealButtonPushed(app, event)
            Contrast = evalin('base','ContrastReal');
            app.realImage.UserData.ContrastReal = Contrast;
            app.InfoStruct.ContrastReal = Contrast;
            app.InfoStructAnalisis.ContrastReal = Contrast;
            app.InfoStructAnalisisCopy.ContrastReal = Contrast;
        end

        % Menu selected function: RecalculateFFTMenu
        function RecalculateFFTMenuSelected(app, event)
            app.InfoStruct = rmfield(app.InfoStruct,'Transformadas');
            for i=1:length(app.InfoStruct.Energia)
                app.InfoStruct.Transformadas{i} = fft2d(app.InfoStruct.MapasConductancia{i});
            end
            msgbox('FFT maps recalculated.',"Don't give up",'help')
        end

        % Button pushed function: AveragescatteringButton
        function AveragescatteringButtonPushed(app, event)
            averageScattering(app.InfoStructAnalisis)
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            app.NewMenu.Checked = 1;
            app.OldMenu.Checked = 0;
                        
            app.New = true;
        end

        % Menu selected function: OldMenu
        function OldMenuSelected(app, event)
            app.NewMenu.Checked = 0;
            app.OldMenu.Checked = 1;
                        
            app.New = false;
        end

        % Menu selected function: VerticalMenu
        function VerticalMenuSelected(app, event)
            app.VerticalMenu.Checked = 'on';
            app.HorizontalMenu.Checked = 'off';
            app.VerticalHorizontalMenu.Checked = 'off';
            
            app.V = true;
            app.H = false;
        end

        % Menu selected function: HorizontalMenu
        function HorizontalMenuSelected(app, event)
            app.VerticalMenu.Checked = 'off';
            app.HorizontalMenu.Checked = 'on';
            app.VerticalHorizontalMenu.Checked = 'off';
            
            app.V = false;
            app.H = true;
        end

        % Menu selected function: VerticalHorizontalMenu
        function VerticalHorizontalMenuSelected(app, event)
            app.VerticalMenu.Checked = 'off';
            app.HorizontalMenu.Checked = 'off';
            app.VerticalHorizontalMenu.Checked = 'on';
            
            app.V = true;
            app.H = true;
        end

        % Button pushed function: CutasviewButton
        function CutasviewButtonPushed(app, event)
            app.InfoStructAnalisisCopy = cutMatrix(app.InfoStructAnalisis);
            VisualizeCell(app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy);
        end

        % Close request function: blqAnalysisUIFigure
        function blqAnalysisUIFigureCloseRequest(app, event)
            delete(app.mapsPreviewApp)
            delete(app.blqReaderApp)
            delete(app)
        end

        % Button pushed function: LoadInfoStructfromworkspaceButton
        function LoadInfoStructfromworkspaceButtonPushed(app, event)
            app.InfoStruct = readInfo();
            figure(app.blqAnalysisUIFigure)
            msgbox('InfoStruct succesfully loaded.','You are amazing','help')
        end

        % Button pushed function: CutInfoStructButton
        function CutInfoStructButtonPushed(app, event)
            x1 = app.x1EditField.Value;
            x2 = app.x2EditField.Value;
            y1 = app.y1EditField.Value;
            y2 = app.y2EditField.Value;
            
            app.InfoStruct = RecorteQPI(x1,x2,y1,y2,app.InfoStructOriginal);
        end

        % Button pushed function: testButton
        function testButtonPushed(app, event)
            app.visualizeAppReal = analize(app);
%             app.visualizeAppFFT.AnalizeRealLabel.Text = 'Analize FFT';
            initCell(app.visualizeAppReal, app.InfoStruct.MapasConductancia, true)
            [app.InfoData] = showCell(app.visualizeAppReal, app.InfoStruct.MapasConductancia, app.InfoStruct, app.visualizeAppReal.k, true);
            
        end

        % Button pushed function: RestoreButton
        function RestoreButtonPushed(app, event)
            app.InfoStruct = app.InfoStructOriginal;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.blqAnalysisUIFigure = uifigure('Visible', 'off');
            app.blqAnalysisUIFigure.Name = 'blqAnalysis';
            app.blqAnalysisUIFigure.CloseRequestFcn = createCallbackFcn(app, @blqAnalysisUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.blqAnalysisUIFigure);
            app.FileMenu.Text = 'File';
            app.InfoStructtoworkspaceMenu = uimenu(app.FileMenu);
            app.InfoStructtoworkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @InfoStructtoworkspaceMenuSelected, true);
            app.InfoStructtoworkspaceMenu.Text = 'InfoStruct to workspace';
            app.EditMenu = uimenu(app.blqAnalysisUIFigure);
            app.EditMenu.Text = 'Edit';
            app.RecalculateFFTMenu = uimenu(app.EditMenu);
            app.RecalculateFFTMenu.MenuSelectedFcn = createCallbackFcn(app, @RecalculateFFTMenuSelected, true);
            app.RecalculateFFTMenu.Text = 'Recalculate FFT';
            app.RemoveCentralLineMenu = uimenu(app.EditMenu);
            app.RemoveCentralLineMenu.Text = 'Remove Central Line';
            app.VerticalMenu = uimenu(app.RemoveCentralLineMenu);
            app.VerticalMenu.MenuSelectedFcn = createCallbackFcn(app, @VerticalMenuSelected, true);
            app.VerticalMenu.Checked = 'on';
            app.VerticalMenu.Text = 'Vertical';
            app.HorizontalMenu = uimenu(app.RemoveCentralLineMenu);
            app.HorizontalMenu.MenuSelectedFcn = createCallbackFcn(app, @HorizontalMenuSelected, true);
            app.HorizontalMenu.Text = 'Horizontal';
            app.VerticalHorizontalMenu = uimenu(app.RemoveCentralLineMenu);
            app.VerticalHorizontalMenu.MenuSelectedFcn = createCallbackFcn(app, @VerticalHorizontalMenuSelected, true);
            app.VerticalHorizontalMenu.Text = 'Vertical + Horizontal';
            app.SymmetrizeMenu = uimenu(app.blqAnalysisUIFigure);
            app.SymmetrizeMenu.Text = 'Symmetrize';
            app.VerticalmirrorMenu = uimenu(app.SymmetrizeMenu);
            app.VerticalmirrorMenu.MenuSelectedFcn = createCallbackFcn(app, @VerticalmirrorMenuSelected, true);
            app.VerticalmirrorMenu.Text = 'Vertical mirror';
            app.VerticalHorizontalmirrorMenu = uimenu(app.SymmetrizeMenu);
            app.VerticalHorizontalmirrorMenu.MenuSelectedFcn = createCallbackFcn(app, @VerticalHorizontalmirrorMenuSelected, true);
            app.VerticalHorizontalmirrorMenu.Text = 'Vertical + Horizontal mirror';
            app.C4symmetryMenu = uimenu(app.SymmetrizeMenu);
            app.C4symmetryMenu.MenuSelectedFcn = createCallbackFcn(app, @C4symmetryMenuSelected, true);
            app.C4symmetryMenu.Checked = 'on';
            app.C4symmetryMenu.Text = 'C4 symmetry';
            app.CellMenu = uimenu(app.blqAnalysisUIFigure);
            app.CellMenu.Text = 'Cell';
            app.NewMenu = uimenu(app.CellMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Checked = 'on';
            app.NewMenu.Text = 'New';
            app.OldMenu = uimenu(app.CellMenu);
            app.OldMenu.MenuSelectedFcn = createCallbackFcn(app, @OldMenuSelected, true);
            app.OldMenu.Text = 'Old';
            app.TabGroup = uitabgroup(app.blqAnalysisUIFigure);
            app.OpenTab = uitab(app.TabGroup);
            app.OpenTab.Title = 'Open';
            app.OldNewButton = uibutton(app.OpenTab, 'push');
            app.OldNewButton.ButtonPushedFcn = createCallbackFcn(app, @OldNewButtonPushed, true);
            app.OldNewButton.Visible = 'off';
            app.OldNewButton.Tooltip = {'Pincha aqúi'; ' subnormal'};
            app.OldNewButton.Text = 'Old New';
            app.SaveInfostructButton = uibutton(app.OpenTab, 'push');
            app.SaveInfostructButton.ButtonPushedFcn = createCallbackFcn(app, @SaveInfostructButtonPushed, true);
            app.SaveInfostructButton.Text = 'Save Infostruct';
            app.LoadInfostructfromfileButton = uibutton(app.OpenTab, 'push');
            app.LoadInfostructfromfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadInfostructfromfileButtonPushed, true);
            app.LoadInfostructfromfileButton.Text = 'Load Infostruct from file';
            app.ClearallButton = uibutton(app.OpenTab, 'push');
            app.ClearallButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallButtonPushed, true);
            app.ClearallButton.Text = 'Clear all';
            app.NewAppButton = uibutton(app.OpenTab, 'push');
            app.NewAppButton.ButtonPushedFcn = createCallbackFcn(app, @NewAppButtonPushed, true);
            app.NewAppButton.Text = 'Open blqReader';
            app.LoadInfoStructfromworkspaceButton = uibutton(app.OpenTab, 'push');
            app.LoadInfoStructfromworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadInfoStructfromworkspaceButtonPushed, true);
            app.LoadInfoStructfromworkspaceButton.Text = 'Load InfoStruct from workspace';
            app.CutInfoStructButton = uibutton(app.OpenTab, 'push');
            app.CutInfoStructButton.ButtonPushedFcn = createCallbackFcn(app, @CutInfoStructButtonPushed, true);
            app.CutInfoStructButton.Text = 'Cut InfoStruct';
            app.x1EditFieldLabel = uilabel(app.OpenTab);
            app.x1EditFieldLabel.Text = 'x1';
            app.x1EditField = uieditfield(app.OpenTab, 'numeric');
            app.x1EditField.Value = 1;
            app.x2EditFieldLabel = uilabel(app.OpenTab);
            app.x2EditFieldLabel.Text = 'x2';
            app.x2EditField = uieditfield(app.OpenTab, 'numeric');
            app.x2EditField.Value = 128;
            app.y1EditFieldLabel = uilabel(app.OpenTab);
            app.y1EditFieldLabel.Text = 'y1';
            app.y1EditField = uieditfield(app.OpenTab, 'numeric');
            app.y1EditField.Value = 1;
            app.y2EditFieldLabel = uilabel(app.OpenTab);
            app.y2EditFieldLabel.Text = 'y2';
            app.y2EditField = uieditfield(app.OpenTab, 'numeric');
            app.y2EditField.Value = 128;
            app.RestoreButton = uibutton(app.OpenTab, 'push');
            app.RestoreButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreButtonPushed, true);
            app.RestoreButton.Text = 'Restore';
            app.AnalizeRealTab = uitab(app.TabGroup);
            app.AnalizeRealTab.Title = 'Analize Real';
            app.RealMapsButton = uibutton(app.AnalizeRealTab, 'push');
            app.RealMapsButton.ButtonPushedFcn = createCallbackFcn(app, @RealMapsButtonPushed, true);
            app.RealMapsButton.Text = 'Real Maps';
            app.ColormapRealDropDown = uidropdown(app.AnalizeRealTab);
            app.ColormapRealDropDown.Items = {'gray', 'parula', 'jet', 'winter', 'hot', 'spring', 'summer', 'autumn', 'bone', 'copper', 'JapanColormap'};
            app.ColormapRealDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapRealDropDownValueChanged, true);
            app.ColormapRealDropDown.Value = 'gray';
            app.KeepChangesButton = uibutton(app.AnalizeRealTab, 'push');
            app.KeepChangesButton.ButtonPushedFcn = createCallbackFcn(app, @KeepChangesButtonPushed, true);
            app.KeepChangesButton.Text = 'Keep Changes';
            app.VisualizeRealButton = uibutton(app.AnalizeRealTab, 'push');
            app.VisualizeRealButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeRealButtonPushed, true);
            app.VisualizeRealButton.Text = 'Visualize Real';
            app.MeanConductanceButton = uibutton(app.AnalizeRealTab, 'push');
            app.MeanConductanceButton.ButtonPushedFcn = createCallbackFcn(app, @MeanConductanceButtonPushed, true);
            app.MeanConductanceButton.Text = 'Mean Conductance';
            app.RotateButton_2 = uibutton(app.AnalizeRealTab, 'push');
            app.RotateButton_2.ButtonPushedFcn = createCallbackFcn(app, @RotateButton_2Pushed, true);
            app.RotateButton_2.Text = 'Rotate';
            app.EditRotateAngleReal = uieditfield(app.AnalizeRealTab, 'numeric');
            app.SaveallRealButton = uibutton(app.AnalizeRealTab, 'push');
            app.SaveallRealButton.ButtonPushedFcn = createCallbackFcn(app, @SaveallRealButtonPushed, true);
            app.SaveallRealButton.Text = 'Save all Real';
            app.GaussianFilterButton_2 = uibutton(app.AnalizeRealTab, 'push');
            app.GaussianFilterButton_2.ButtonPushedFcn = createCallbackFcn(app, @GaussianFilterButton_2Pushed, true);
            app.GaussianFilterButton_2.Text = 'Gaussian Filter';
            app.EditGaussianFilterReal = uieditfield(app.AnalizeRealTab, 'numeric');
            app.MeanIVButton = uibutton(app.AnalizeRealTab, 'push');
            app.MeanIVButton.ButtonPushedFcn = createCallbackFcn(app, @MeanIVButtonPushed, true);
            app.MeanIVButton.Text = 'Mean IV';
            app.SaveContrastRealButton = uibutton(app.AnalizeRealTab, 'push');
            app.SaveContrastRealButton.ButtonPushedFcn = createCallbackFcn(app, @SaveContrastRealButtonPushed, true);
            app.SaveContrastRealButton.Text = 'Save Contrast';
            app.LoadContrastRealButton = uibutton(app.AnalizeRealTab, 'push');
            app.LoadContrastRealButton.ButtonPushedFcn = createCallbackFcn(app, @LoadContrastRealButtonPushed, true);
            app.LoadContrastRealButton.Text = 'Load Contrast';
            app.testButton = uibutton(app.AnalizeRealTab, 'push');
            app.testButton.ButtonPushedFcn = createCallbackFcn(app, @testButtonPushed, true);
            app.testButton.Text = 'test';
            app.AnalizeFFTTab = uitab(app.TabGroup);
            app.AnalizeFFTTab.Title = 'Analize FFT';
            app.FFTMapsButton = uibutton(app.AnalizeFFTTab, 'push');
            app.FFTMapsButton.ButtonPushedFcn = createCallbackFcn(app, @FFTMapsButtonPushed, true);
            app.FFTMapsButton.Text = 'FFT Maps';
            app.ColormapFFTDropDown = uidropdown(app.AnalizeFFTTab);
            app.ColormapFFTDropDown.Items = {'gray', 'parula', 'jet', 'winter', 'hot', 'spring', 'summer', 'autumn', 'bone', 'copper', 'JapanColormap'};
            app.ColormapFFTDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapFFTDropDownValueChanged, true);
            app.ColormapFFTDropDown.Value = 'gray';
            app.RemoveCentralLineButton = uibutton(app.AnalizeFFTTab, 'push');
            app.RemoveCentralLineButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveCentralLineButtonPushed, true);
            app.RemoveCentralLineButton.Text = 'Remove Central Line';
            app.RotateButton = uibutton(app.AnalizeFFTTab, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.EditRotateAngleFFT = uieditfield(app.AnalizeFFTTab, 'numeric');
            app.SymmetrizeButton = uibutton(app.AnalizeFFTTab, 'push');
            app.SymmetrizeButton.ButtonPushedFcn = createCallbackFcn(app, @SymmetrizeButtonPushed, true);
            app.SymmetrizeButton.Text = 'Symmetrize';
            app.AdjustZeroButton = uibutton(app.AnalizeFFTTab, 'push');
            app.AdjustZeroButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustZeroButtonPushed, true);
            app.AdjustZeroButton.Text = 'Adjust (0,0)';
            app.SaveallFFTButton = uibutton(app.AnalizeFFTTab, 'push');
            app.SaveallFFTButton.ButtonPushedFcn = createCallbackFcn(app, @SaveallFFTButtonPushed, true);
            app.SaveallFFTButton.Text = 'Save all FFT';
            app.GaussianFilterButton = uibutton(app.AnalizeFFTTab, 'push');
            app.GaussianFilterButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianFilterButtonPushed, true);
            app.GaussianFilterButton.Text = 'Gaussian Filter';
            app.EditGaussianFilterFFT = uieditfield(app.AnalizeFFTTab, 'numeric');
            app.AngleprofileButton = uibutton(app.AnalizeFFTTab, 'push');
            app.AngleprofileButton.Text = 'Angle profile';
            app.EditProfileAngle = uieditfield(app.AnalizeFFTTab, 'numeric');
            app.RadialprofileButton = uibutton(app.AnalizeFFTTab, 'push');
            app.RadialprofileButton.Text = 'Radial profile';
            app.VisualizeFFTButton = uibutton(app.AnalizeFFTTab, 'push');
            app.VisualizeFFTButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeFFTButtonPushed, true);
            app.VisualizeFFTButton.Text = 'Visualize FFT';
            app.KeepChangesButton_2 = uibutton(app.AnalizeFFTTab, 'push');
            app.KeepChangesButton_2.ButtonPushedFcn = createCallbackFcn(app, @KeepChangesButton_2Pushed, true);
            app.KeepChangesButton_2.Text = 'Keep Changes';
            app.VerticalprofileButton = uibutton(app.AnalizeFFTTab, 'push');
            app.VerticalprofileButton.ButtonPushedFcn = createCallbackFcn(app, @VerticalprofileButtonPushed, true);
            app.VerticalprofileButton.Text = 'Vertical profile';
            app.HorizontalprofileButton = uibutton(app.AnalizeFFTTab, 'push');
            app.HorizontalprofileButton.ButtonPushedFcn = createCallbackFcn(app, @HorizontalprofileButtonPushed, true);
            app.HorizontalprofileButton.Text = 'Horizontal profile';
            app.DiagonalprofileButton = uibutton(app.AnalizeFFTTab, 'push');
            app.DiagonalprofileButton.ButtonPushedFcn = createCallbackFcn(app, @DiagonalprofileButtonPushed, true);
            app.DiagonalprofileButton.Text = 'Diagonal profile';
            app.SaveContrastFFTButton = uibutton(app.AnalizeFFTTab, 'push');
            app.SaveContrastFFTButton.ButtonPushedFcn = createCallbackFcn(app, @SaveContrastFFTButtonPushed, true);
            app.SaveContrastFFTButton.Text = 'Save Contrast';
            app.LoadContrastFFTButton = uibutton(app.AnalizeFFTTab, 'push');
            app.LoadContrastFFTButton.ButtonPushedFcn = createCallbackFcn(app, @LoadContrastFFTButtonPushed, true);
            app.LoadContrastFFTButton.Text = 'Load Contrast';
            app.CutasviewButton = uibutton(app.AnalizeFFTTab, 'push');
            app.CutasviewButton.ButtonPushedFcn = createCallbackFcn(app, @CutasviewButtonPushed, true);
            app.CutasviewButton.Text = 'Cut as view';
            app.MoreFunctionsTab = uitab(app.TabGroup);
            app.MoreFunctionsTab.Title = 'More Functions';
            app.AveragescatteringButton = uibutton(app.MoreFunctionsTab, 'push');
            app.AveragescatteringButton.ButtonPushedFcn = createCallbackFcn(app, @AveragescatteringButtonPushed, true);
            app.AveragescatteringButton.Text = 'Average scattering';
            app.blqAnalysisQPIStudyLabel = uilabel(app.blqAnalysisUIFigure);
            app.blqAnalysisQPIStudyLabel.Text = 'blqAnalysis + QPI Study';
            % Show the figure after all components are created
            app.blqAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = blqApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.blqAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.blqAnalysisUIFigure)
        end
    end
end